classdef TPMS_Designer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        GridLeft                        matlab.ui.container.GridLayout
        ResetButton                     matlab.ui.control.Button
        ProcessDataButton               matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        GeneralTab                      matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        resolution                      matlab.ui.control.NumericEditField
        resolutionSlider                matlab.ui.control.Slider
        ResolutionseednodesSliderLabel  matlab.ui.control.Label
        TargetVolumeFractionLabel       matlab.ui.control.Label
        volumeSlider                    matlab.ui.control.Slider
        vftext                          matlab.ui.control.NumericEditField
        v2EditField                     matlab.ui.control.NumericEditField
        v2EditFieldLabel                matlab.ui.control.Label
        Isovaluesv1EditField            matlab.ui.control.NumericEditField
        Isovaluesv1EditFieldLabel       matlab.ui.control.Label
        TypeDropDownLabel               matlab.ui.control.Label
        TypeDropDown                    matlab.ui.control.DropDown
        EquationText                    matlab.ui.control.TextArea
        EquationDropDownLabel           matlab.ui.control.Label
        EquationDropDown                matlab.ui.control.DropDown
        TransformationsTab              matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        RotationPitchRollYawindegPanel  matlab.ui.container.Panel
        GridLayout9_6                   matlab.ui.container.GridLayout
        yaw                             matlab.ui.control.NumericEditField
        roll                            matlab.ui.control.NumericEditField
        pitch                           matlab.ui.control.NumericEditField
        PhaseShiftBxByBzPanel           matlab.ui.container.Panel
        GridLayout9_5                   matlab.ui.container.GridLayout
        bz                              matlab.ui.control.NumericEditField
        by                              matlab.ui.control.NumericEditField
        bx                              matlab.ui.control.NumericEditField
        NumberofCellsnxnynzPanel        matlab.ui.container.Panel
        GridLayout9_4                   matlab.ui.container.GridLayout
        ChangeAllLabel_2                matlab.ui.control.Label
        nslider                         matlab.ui.control.Slider
        nz                              matlab.ui.control.NumericEditField
        ny                              matlab.ui.control.NumericEditField
        nx                              matlab.ui.control.NumericEditField
        CellSizeLxLyLzinmmPanel         matlab.ui.container.Panel
        GridLayout9_3                   matlab.ui.container.GridLayout
        ChangeAllLabel                  matlab.ui.control.Label
        Lslider                         matlab.ui.control.Slider
        lz                              matlab.ui.control.NumericEditField
        ly                              matlab.ui.control.NumericEditField
        lx                              matlab.ui.control.NumericEditField
        OptionsToolsTab                 matlab.ui.container.Tab
        GridLayout8                     matlab.ui.container.GridLayout
        VisualisationOptionsPanel       matlab.ui.container.Panel
        GridLayout10_4                  matlab.ui.container.GridLayout
        polemapdivs                     matlab.ui.control.Spinner
        PolemapdivisionsLabel           matlab.ui.control.Label
        cmap                            matlab.ui.control.DropDown
        ColormapDropDownLabel           matlab.ui.control.Label
        hbuckets                        matlab.ui.control.Spinner
        HistogramBucketsSpinnerLabel    matlab.ui.control.Label
        ImportExportPanel               matlab.ui.container.Panel
        GridLayout10_3                  matlab.ui.container.GridLayout
        GridLayout11                    matlab.ui.container.GridLayout
        VoxelSizemmEditFieldLabel       matlab.ui.control.Label
        VoxelSizemmEditField            matlab.ui.control.NumericEditField
        ImportsurfacemeshstlButton      matlab.ui.control.Button
        ExportvoxelmeshinpButton        matlab.ui.control.Button
        ExportsurfacemeshstlButton      matlab.ui.control.Button
        ExportfigurefigButton           matlab.ui.control.Button
        ExportmetrichistorycsvButton    matlab.ui.control.Button
        CalculationoptionsmayslowperformancePanel  matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        mechanical                      matlab.ui.control.CheckBox
        curvature                       matlab.ui.control.DropDown
        CurvatureMethodDropDownLabel    matlab.ui.control.Label
        TPMSDesignerGUIv23Label         matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        GridRight                       matlab.ui.container.GridLayout
        InitialisingLabel               matlab.ui.control.Label
        Slider                          matlab.ui.control.Slider
        FaceProperty2Label              matlab.ui.control.Label
        P2                              matlab.ui.control.DropDown
        P1                              matlab.ui.control.DropDown
        FacePropertyLabel               matlab.ui.control.Label
        type                            matlab.ui.control.DropDown
        typeText                        matlab.ui.control.Label
        ax                              matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        myTPMS TPMS; %Single unit cell
        cellhistory; %Array/List of unit cells
        cellID = 1; %ID of current cell (starts at 1)
        stlmesh; %Inported Mesh
        opts; %General options
        savedView = 3; %Store 3d view data
    end
    
    methods (Access = private)
        function updateFigure(app)
            %Update all Cell Figures
            cla(app.ax);  opt = app.opts;

            switch opt.figureType
                case 'metrics scatterplot'
                    x = app.cellhistory.(app.P1.Value);
                    y = app.cellhistory.(app.P2.Value);
                    scatter(app.ax,x,y,'red',"filled"); grid(app.ax,"on");
                    xlabel(app.ax,app.P1.Value);ylabel(app.ax,app.P2.Value)
                case 'field viewer'
                    pID = convertStringsToChars(extractBefore(app.P1.Value+" ", " "));
                    F = app.myTPMS.F;

                    if isfield(F.property,pID)
                        cData1 = F.property.(pID);
                    else
                        cData1 = zeros(size(F.property.X));
                    end

                    % Sample Points
                    c = colorbar(app.ax); 
                    patch(app.ax,'Faces',app.myTPMS.FV.faces,'Vertices',app.myTPMS.FV.vertices,'FaceVertexCData',[0 0 0.25],'FaceAlpha',0.2,'LineStyle','none'); hold(app.ax,'on');
                    [xq, yq, zq] = meshgrid(F.xq,F.yq,app.Slider.Value/100*F.upper(3));
                    xyslice = interp3(F.property.X,F.property.Y,F.property.Z,cData1,xq,yq,zq);
                    surf(app.ax,xq,yq,zq,xyslice,'LineStyle',opt.meshDisp);
                    c.Label.String = 'Field Value'; colormap(app.ax,"jet");
                case 'polemap'
                    opt.n = app.polemapdivs.Value;
                    app.myTPMS.plot(app.ax,opt.figureType,app.P1.Value,app.P2.Value,opt);
                otherwise
                    app.myTPMS.plot(app.ax,opt.figureType,app.P1.Value,app.P2.Value,opt);
            end
            set(app.ax,'ColorScale',app.cmap.Value)
            drawnow();
        end
        
        function runCurrent(app)
            tic %Start the timer
            app.ProcessDataButton.BackgroundColor = 'Yellow';
            app.ProcessDataButton.Text = 'Updating Please Wait...';
            drawnow();
            try
            % Fundamental Properties/Parameters 
            equation = string(app.EquationDropDown.Value);
            tpmstype = string(app.TypeDropDown.Value);

            switch tpmstype
                case "double"
                    v = app.Isovaluesv1EditField.Value;
                    vf = app.v2EditField.Value;
                case "single"
                    vf = app.Isovaluesv1EditField.Value;
                    v = [];
                otherwise
                    vf = app.volumeSlider.Value;
                    v = [];
            end

            if strcmp(equation,'Custom')
                equation = app.EquationText.Value{1};
            end

            res = app.resolutionSlider.Value;
            cellSize = [app.lx.Value app.ly.Value app.lz.Value];
            ncells = [app.nx.Value app.ny.Value app.nz.Value];
            Rxyz = [app.pitch.Value app.roll.Value app.yaw.Value];
            offset = [app.bx.Value app.by.Value app.bz.Value];
            app.myTPMS = TPMS(equation,tpmstype,v,vf,res,cellSize,ncells,Rxyz,offset);
            
            % Deal with Object/custom importing
            if contains(equation,".stl")
                    % Apply transformations now
                    app.myTPMS.FV = app.stlmesh;
                    app.myTPMS.F = app.myTPMS.FV.getField(app.VoxelSizemmEditField.Value);
                    app.myTPMS.res = app.myTPMS.F.res;
                    app.myTPMS.cellSize = app.myTPMS.F.upper-app.myTPMS.F.lower;
            end
            
            % Do the things!
            app.myTPMS = app.myTPMS.properties(1,app.mechanical.Value,1,app.curvature.Value);
            
            % Save the metric information
            if ~contains(equation,".stl")
                temp = [table(app.cellID,'VariableNames',{'ID'}) struct2table(app.myTPMS.export,'AsArray',true)];
                app.cellID = app.cellID+1;
                if isempty(app.cellhistory)
                    app.cellhistory = temp;
                else
                    app.cellhistory = vertcat(app.cellhistory,temp);
                end
            end

            app.EquationText.Value = char(app.myTPMS.u);
            app.Isovaluesv1EditField.Value = app.myTPMS.v;
            app.v2EditField.Value = app.myTPMS.vf;
            updateFigure(app);
            
            txt = "Last operation successful after: "+string(toc)+"seconds";
            catch

                txt = "Last operation failed after: "+string(toc)+"seconds";
            end

            app.ProcessDataButton.BackgroundColor = 'Green';
            app.ProcessDataButton.Text = 'Update';
            app.InitialisingLabel.Text = txt;
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Define default options
            axis(app.ax,"equal","vis3d","tight");
            app.opts.curvature = 'patch';
            app.opts.figureType = 'surfaceMesh';
            app.opts.meshDisp = '-';
            app.opts.colorscale = 'linear';
            app.opts.currentProperty2 = app.P2.Value;
            app.opts.currentProperty = app.P1.Value;
            app.opts.currentMetric2 = "volume";
            app.opts.currentMetric1 = "ID";
            app.FaceProperty2Label.Text = ''; app.P2.Visible = false;
            runCurrent(app);
            app.P1.Items = [fieldnames(app.myTPMS.FV.Fproperty); fieldnames(app.myTPMS.FV.Vproperty)];
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {697, 697};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {358, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: ExportsurfacemeshstlButton
        function exportMesh(app, event)
            [file,path] = uiputfile('*.stl');
            if ~isempty(app.myTPMS.FV.facesC)
                F = [app.myTPMS.FV.faces; app.myTPMS.FV.facesC+length(app.myTPMS.FV.vertices)];
                V = [app.myTPMS.FV.vertices; app.myTPMS.FV.verticesC];
            elseif ~isempty(app.myTPMS.FV.faces)
                F = app.myTPMS.FV.faces;
                V = app.myTPMS.FV.vertices;
            else
                return;
            end
            TR = triangulation(F,V);
            stlwrite(TR,string(fullfile(path,file)));
        end

        % Button pushed function: ExportvoxelmeshinpButton
        function exportINP(app, event)
            [file,path] = uiputfile('*.inp');
            app.myTPMS.F.exportINP(fullfile(path,file));
        end

        % Button pushed function: ExportfigurefigButton
        function exportFig(app, event)
            %Copy axes to new figure and save
            [file,path] = uiputfile('*.fig');
            tempfig = figure(); % New figure
            savedFig = gca; % New Figure Axis Handle
            % Copy children.
            copyobj(app.ax.Children, savedFig);
            % Transfer other properties.
            uiAxParams = get(app.ax);
            uiAxParamNames = fieldnames(uiAxParams);
            % Get list of editable params in new axis
            editableParams = fieldnames(set(app.ax));
            % Remove the UIAxes params that aren't editable in the new axes (add others you don't want)
            badFields = uiAxParamNames(~ismember(uiAxParamNames, editableParams));
            badFields = [badFields; 'Parent'; 'Children'; 'XAxis'; 'YAxis'; 'ZAxis'; 'Title'; 'Position';...
                'Clipping'; 'Layout'; 'BoxStyle';'InnerPosition';'OuterPosition';'BackgroundColor';'SizeChangedFcn'];
            uiAxGoodParams = rmfield(uiAxParams,badFields);
            % set editable params on new axes
            set(savedFig, uiAxGoodParams)
            % Add Colorbar
            if file
                savefig(tempfig,string(fullfile(path,file)));
            end
        end

        % Button pushed function: ExportmetrichistorycsvButton
        function exportMetrics(app, event)
            [file,path] = uiputfile('*.csv');
            writetable(app.cellhistory,fullfile(path,file))
        end

        % Button pushed function: ImportsurfacemeshstlButton
        function importSTL(app, event)
            [file,path] = uigetfile('*.stl');
            app.curvature.Value = 'patch';
            app.stlmesh = surfaceMesh('stl',string(fullfile(path,file))); % Create the surfaceMesh object from the .stl file
            app.EquationText.Value = file;
            
            app.EquationDropDown.Items = [app.EquationDropDown.Items(1:length(app.EquationDropDown.Items)) {file}];
            app.EquationDropDown.Value = file;
            runCurrent(app);
        end

        % Value changed function: P1, P2, cmap, hbuckets, polemapdivs
        function changeOpts(app, event)
            switch event.Source
                case app.P1 % Change Property 1
                    app.opts.currentProperty = app.P1.Value;
                case app.P2 % Change Property 2
                    app.opts.currentProperty2 = app.P2.Value; 
                case app.linearMenu
                    app.linearMenu.Checked = 'on';
                    app.logMenu.Checked = 'off';
                    app.opts.colorscale = 'linear';
                case app.logMenu
                    app.linearMenu.Checked = 'off';
                    app.logMenu.Checked = 'on';
                    app.opts.colorscale = 'log';
            end


            updateFigure(app);
        end

        % Value changed function: type
        function changeVisulisation(app, event)
            try
            switch app.opts.figureType
                case "surfaceMesh"
                    app.savedView = get(app.ax,"view"); % Save view
                case "polemap"
                    app.savedView = get(app.ax,"view"); % Save view
                case "field viewer"
                    app.savedView = get(app.ax,"view"); % Save view
                case "voxel"
                    app.savedView = get(app.ax,"view"); % Save view
                case "tensor"
                    app.savedView = get(app.ax,"view"); % Save view
            end
            
            app.opts.figureType = event.Source.Value;
            cla(app.ax,'reset'); % Hard Clear Figures and viewport options
           
            
            switch app.opts.figureType
                case "surfaceMesh"
                    view(app.ax,app.savedView); axis(app.ax,"equal","vis3d","tight");
                    app.FacePropertyLabel.Text = "Property"; app.P1.Visible = true;
                    app.FaceProperty2Label.Text = ''; app.P2.Visible = false;
                    app.Slider.Visible = false;
                    app.P1.Items = [fieldnames(app.myTPMS.FV.Fproperty); fieldnames(app.myTPMS.FV.Vproperty)];
                    app.P1.Value = app.P1.Items(1);
                case "polemap"
                    view(app.ax,app.savedView); axis(app.ax,"equal","vis3d","tight");
                    app.FacePropertyLabel.Text = ''; app.P1.Visible = false;
                    app.FaceProperty2Label.Text = ''; app.P2.Visible = false;
                    app.Slider.Visible = false;
                case "tensor"
                    view(app.ax,app.savedView); axis(app.ax,"equal","vis3d","tight");
                    app.FacePropertyLabel.Text = ''; app.P1.Visible = false;
                    app.FaceProperty2Label.Text = ''; app.P2.Visible = false;
                    app.Slider.Visible = false;
                case "histogram"
                    app.FacePropertyLabel.Text = "Property"; app.P1.Visible = true;
                    app.FaceProperty2Label.Text = ''; app.P2.Visible = false;
                    app.Slider.Visible = false;
                    app.P1.Items = [fieldnames(app.myTPMS.FV.Fproperty); fieldnames(app.myTPMS.FV.Vproperty)];
                case "histogram2"
                    app.FacePropertyLabel.Text = "Face Property"; app.P1.Visible = true;
                    app.FaceProperty2Label.Text = "Face Property 2"; app.P2.Visible = true;
                    app.Slider.Visible = false;
                    app.P1.Items = [fieldnames(app.myTPMS.FV.Fproperty); fieldnames(app.myTPMS.FV.Vproperty)]; app.P1.Value = app.P1.Items(1);
                    app.P2.Items = [fieldnames(app.myTPMS.FV.Fproperty); fieldnames(app.myTPMS.FV.Vproperty)]; app.P2.Value = app.P2.Items(2);
                case "metrics scatterplot"
                    app.FacePropertyLabel.Text = "Cell Properties"; app.P1.Visible = true;
                    app.P2.Visible = true;
                    app.Slider.Visible = false;
                    app.P1.Items = app.cellhistory.Properties.VariableNames; app.P1.Value = app.P1.Items(1);
                    app.P2.Items = app.cellhistory.Properties.VariableNames; app.P2.Value = app.P2.Items(2);
                case "field viewer"
                    view(app.ax,app.savedView); axis(app.ax,"equal","vis3d","tight");
                    app.FacePropertyLabel.Text = "Field Name"; app.P1.Visible = true;
                    app.FaceProperty2Label.Text = "Z-Slice"; app.P2.Visible = false;
                    app.Slider.Visible = true;
                    app.P1.Items = fieldnames(app.myTPMS.F.property); app.P1.Value = app.P1.Items(1);
                case "voxel"
                    view(app.ax,app.savedView); axis(app.ax,"equal","vis3d","tight");
                    app.FacePropertyLabel.Text = "Field Name"; app.P1.Visible = true;
                    app.FaceProperty2Label.Text = ''; app.P2.Visible = false;
                    app.Slider.Visible = false;
                    app.P1.Items = fieldnames(app.myTPMS.F.property); app.P1.Value = 'solid';
                otherwise
                    view(app.ax,app.savedView); axis(app.ax,"equal","vis3d","tight");
                    app.P1.Items = fieldnames(app.myTPMS.F.property); app.P1.Value = app.P1.Items(1);
            end
            updateFigure(app);
            catch
            end
        end

        % Callback function: ProcessDataButton, UIFigure
        function updateButton(app, event)
            % Deal with two input options
            runCurrent(app);
        end

        % Value changing function: Slider
        function sliceSliderChanging(app, event)
            znew = event.Value;
            F = app.myTPMS.F;
            [xq, yq, zq] = meshgrid(F.xq,F.yq,znew/100*F.upper(3));
            pID = convertStringsToChars(extractBefore(app.P1.Value+" ", " "));
            if isfield(F.property,pID)
                cData = F.property.(pID);
            else
                cData = F.(pID);
            end
            app.ax.Children(1).CData = interp3(F.property.X,F.property.Y,F.property.Z,cData,xq,yq,zq);
            app.ax.Children(1).ZData = zq;
        end

        % Button pushed function: ResetButton
        function resetButton(app, event)
            app.lx.Value = 10; app.ly.Value = 10; app.lz.Value = 10;
            app.Lslider.Value = 10;
            app.bx.Value = 0; app.by.Value = 0; app.bz.Value = 0;
            app.nx.Value = 1; app.ny.Value = 1; app.nz.Value = 1;
            app.nslider.Value = 1;
            app.pitch.Value = 0;app.roll.Value = 0; app.yaw.Value = 0;
            app.resolutionSlider.Value = 25; app.resolution.Value = 25;
            app.TypeDropDown.Value = 'network';
            app.EquationDropDown.Value = 'Gyroid'; 
            app.volumeSlider.Value = 0.25; app.vftext.Value = 0.25;
            app.cmap.Value = 'linear'; app.hbuckets.Value = 100;
            app.polemapdivs.Value = 3;
            app.cellhistory = []; app.cellID = 1;
            runCurrent(app);
        end

        % Callback function: EquationDropDown, EquationText, 
        % ...and 15 other components
        function sliderChange(app, event)
            switch event.Source
                case app.resolution
                    app.resolutionSlider.Value = event.Value;
                case app.vftext
                    app.volumeSlider.Value = event.Value;
                case app.resolutionSlider
                    app.resolution.Value = event.Value;
                case app.volumeSlider
                    app.vftext.Value = event.Value;
                case app.Lslider
                    app.lx.Value = event.Value;
                    app.ly.Value = event.Value;
                    app.lz.Value = event.Value;
                case app.nslider
                    app.nx.Value = event.Value;
                    app.ny.Value = event.Value;
                    app.nz.Value = event.Value;
                case app.TypeDropDown
                    switch app.TypeDropDown.Value
                        case "double"
                            app.volumeSlider.Visible = 0;
                            app.TargetVolumeFractionLabel.Visible = 0;
                            app.Isovaluesv1EditField.BackgroundColor = [1 1 1];
                            app.vftext.Visible = 0;
                            app.Isovaluesv1EditField.Editable = 1;
                            app.v2EditField.Visible = 1;
                            app.v2EditFieldLabel.Visible = 1;
                        case "single"
                            app.volumeSlider.Visible = 0;
                            app.Isovaluesv1EditField.BackgroundColor = [1 1 1];
                            app.TargetVolumeFractionLabel.Visible = 0;
                            app.vftext.Visible = 0;
                            app.Isovaluesv1EditField.Editable = 1;
                            app.v2EditField.Visible = 0;
                            app.v2EditFieldLabel.Visible = 0;
                        otherwise
                            app.volumeSlider.Visible = 1;
                            app.TargetVolumeFractionLabel.Visible = 1;
                            app.vftext.Visible = 1;
                            app.Isovaluesv1EditField.BackgroundColor = [0.9 0.9 0.9];
                            app.Isovaluesv1EditField.Editable = 0;
                            app.v2EditField.Visible = 0;
                            app.v2EditFieldLabel.Visible = 0;
                    end
                case app.EquationText
                    app.EquationDropDown.Value = "Custom";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @updateButton, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLeft = uigridlayout(app.LeftPanel);
            app.TPMSDesignerGUIv23Label = uilabel(app.GridLeft);
            app.TPMSDesignerGUIv23Label.Text = 'TPMS Designer GUI - v2.3';
            app.TabGroup = uitabgroup(app.GridLeft);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.GridLayout5 = uigridlayout(app.GeneralTab);
            app.EquationDropDown = uidropdown(app.GridLayout5);
            app.EquationDropDown.Items = {'Gyroid', 'Diamond', 'Primitive', 'IWP', 'Neovius', 'FRD', 'Sinusoidal', 'Sphere', 'P-normCube', 'Taurus', 'Custom'};
            app.EquationDropDown.ValueChangedFcn = createCallbackFcn(app, @sliderChange, true);
            app.EquationDropDown.Tooltip = {'Select the equation you wish to generate.The most common TPMS (Gyroid, Diamond, Primitive) have been included along with some sample implicit objects (Sinusoidal, Sphere, PnormCube, Taurus).'; ''; '"Custom" to modify or write a new equation.  '; 'All entries after Custom will be imported structures (.stl or lattices)'};
            app.EquationDropDown.Value = 'Gyroid';
            app.EquationDropDownLabel = uilabel(app.GridLayout5);
            app.EquationDropDownLabel.Tooltip = {''};
            app.EquationDropDownLabel.Text = 'Equation';
            app.EquationText = uitextarea(app.GridLayout5);
            app.EquationText.ValueChangedFcn = createCallbackFcn(app, @sliderChange, true);
            app.EquationText.Tooltip = {'Enter your custom function here. Must be a valid matlab function with the format for example: @(x,y,z) x.*y+z-3*x '};
            app.EquationText.Value = {'Equation'};
            app.TypeDropDown = uidropdown(app.GridLayout5);
            app.TypeDropDown.Items = {'network', 'surface', 'single', 'double'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @sliderChange, true);
            app.TypeDropDown.Tooltip = {'Select the type of TPMS you wish to generate.'; ''; 'network: Produces a skeletal structure using a target volume fraction as the input the isovalue v1 is defined to achieve close to the target volume fraction. (Finds volume with: U ≤ v1).'; ''; 'surface: Produces a surface with midplane on the zero isosurface at a target volume fraction. This calculates the isovalues +v1 and -v1.'; '(Finds volume with: -v1 ≤ U ≤ v1)'; ''; 'single: Similar to network, but procudes a result directly from the isovalue'; ''; 'double: General form which directly finds the volume bounded by two isovalues v1 and v2.'; '(Finds volume with: v1 ≤ U ≤ v2)'};
            app.TypeDropDown.Value = 'network';
            app.TypeDropDownLabel = uilabel(app.GridLayout5);
            app.TypeDropDownLabel.Tooltip = {''};
            app.TypeDropDownLabel.Text = 'Type';
            app.Isovaluesv1EditFieldLabel = uilabel(app.GridLayout5);
            app.Isovaluesv1EditFieldLabel.Text = 'Isovalue(s):   v1';
            app.Isovaluesv1EditField = uieditfield(app.GridLayout5, 'numeric');
            app.Isovaluesv1EditField.ValueDisplayFormat = '%11.2g';
            app.Isovaluesv1EditField.ValueChangedFcn = createCallbackFcn(app, @sliderChange, true);
            app.Isovaluesv1EditField.Editable = 'off';
            app.Isovaluesv1EditField.Tooltip = {'Actual Isovalue Used'};
            app.v2EditFieldLabel = uilabel(app.GridLayout5);
            app.v2EditFieldLabel.Visible = 'off';
            app.v2EditFieldLabel.Text = 'v2';
            app.v2EditField = uieditfield(app.GridLayout5, 'numeric');
            app.v2EditField.ValueDisplayFormat = '%11.2g';
            app.v2EditField.ValueChangedFcn = createCallbackFcn(app, @sliderChange, true);
            app.v2EditField.Visible = 'off';
            app.v2EditField.Tooltip = {'Actual Secondary Isovalue used'};
            app.v2EditField.Value = 0.3;
            app.vftext = uieditfield(app.GridLayout5, 'numeric');
            app.vftext.Limits = [0 1];
            app.vftext.ValueDisplayFormat = '%11.2g';
            app.vftext.ValueChangedFcn = createCallbackFcn(app, @sliderChange, true);
            app.vftext.Value = 0.5;
            app.volumeSlider = uislider(app.GridLayout5);
            app.volumeSlider.Limits = [0 1];
            app.volumeSlider.ValueChangingFcn = createCallbackFcn(app, @sliderChange, true);
            app.volumeSlider.Value = 0.25;
            app.TargetVolumeFractionLabel = uilabel(app.GridLayout5);
            app.TargetVolumeFractionLabel.Text = {'Target Volume '; 'Fraction'};
            app.ResolutionseednodesSliderLabel = uilabel(app.GridLayout5);
            app.ResolutionseednodesSliderLabel.Text = {'Resolution '; '(seed nodes)'};
            app.resolutionSlider = uislider(app.GridLayout5);
            app.resolutionSlider.Limits = [2 128];
            app.resolutionSlider.ValueChangingFcn = createCallbackFcn(app, @sliderChange, true);
            app.resolutionSlider.Tooltip = {''};
            app.resolutionSlider.Value = 20;
            app.resolution = uieditfield(app.GridLayout5, 'numeric');
            app.resolution.Limits = [2 128];
            app.resolution.RoundFractionalValues = 'on';
            app.resolution.ValueChangedFcn = createCallbackFcn(app, @sliderChange, true);
            app.resolution.Tooltip = {'Number of nodes per unit cell (in x,y,z directions).'; ''; 'Increasing this will increase the mesh quality at the cost of computational time'};
            app.resolution.Value = 25;
            app.TransformationsTab = uitab(app.TabGroup);
            app.TransformationsTab.Title = 'Transformations';
            app.GridLayout6 = uigridlayout(app.TransformationsTab);
            app.CellSizeLxLyLzinmmPanel = uipanel(app.GridLayout6);
            app.CellSizeLxLyLzinmmPanel.Title = 'Cell Size (Lx, Ly, Lz in mm)';
            app.GridLayout9_3 = uigridlayout(app.CellSizeLxLyLzinmmPanel);
            app.lx = uieditfield(app.GridLayout9_3, 'numeric');
            app.lx.LowerLimitInclusive = 'off';
            app.lx.UpperLimitInclusive = 'off';
            app.lx.Limits = [0 Inf];
            app.lx.ValueChangedFcn = createCallbackFcn(app, @sliderChange, true);
            app.lx.Value = 10;
            app.ly = uieditfield(app.GridLayout9_3, 'numeric');
            app.ly.LowerLimitInclusive = 'off';
            app.ly.UpperLimitInclusive = 'off';
            app.ly.Limits = [0 Inf];
            app.ly.ValueChangedFcn = createCallbackFcn(app, @sliderChange, true);
            app.ly.Value = 10;
            app.lz = uieditfield(app.GridLayout9_3, 'numeric');
            app.lz.LowerLimitInclusive = 'off';
            app.lz.UpperLimitInclusive = 'off';
            app.lz.Limits = [0 Inf];
            app.lz.ValueChangedFcn = createCallbackFcn(app, @sliderChange, true);
            app.lz.Value = 10;
            app.Lslider = uislider(app.GridLayout9_3);
            app.Lslider.Limits = [0 20];
            app.Lslider.ValueChangingFcn = createCallbackFcn(app, @sliderChange, true);
            app.Lslider.Value = 10;
            app.ChangeAllLabel = uilabel(app.GridLayout9_3);
            app.ChangeAllLabel.Text = 'Change All:';
            app.NumberofCellsnxnynzPanel = uipanel(app.GridLayout6);
            app.NumberofCellsnxnynzPanel.Title = 'Number of Cells (nx, ny, nz)';
            app.GridLayout9_4 = uigridlayout(app.NumberofCellsnxnynzPanel);
            app.nx = uieditfield(app.GridLayout9_4, 'numeric');
            app.nx.LowerLimitInclusive = 'off';
            app.nx.UpperLimitInclusive = 'off';
            app.nx.Limits = [0 Inf];
            app.nx.ValueChangedFcn = createCallbackFcn(app, @sliderChange, true);
            app.nx.Value = 1;
            app.ny = uieditfield(app.GridLayout9_4, 'numeric');
            app.ny.LowerLimitInclusive = 'off';
            app.ny.UpperLimitInclusive = 'off';
            app.ny.Limits = [0 Inf];
            app.ny.ValueChangedFcn = createCallbackFcn(app, @sliderChange, true);
            app.ny.Value = 1;
            app.nz = uieditfield(app.GridLayout9_4, 'numeric');
            app.nz.LowerLimitInclusive = 'off';
            app.nz.UpperLimitInclusive = 'off';
            app.nz.Limits = [0 Inf];
            app.nz.ValueChangedFcn = createCallbackFcn(app, @sliderChange, true);
            app.nz.Value = 1;
            app.nslider = uislider(app.GridLayout9_4);
            app.nslider.Limits = [0 5];
            app.nslider.MajorTicks = [0 1 2 3 4 5];
            app.nslider.ValueChangingFcn = createCallbackFcn(app, @sliderChange, true);
            app.nslider.Value = 1;
            app.ChangeAllLabel_2 = uilabel(app.GridLayout9_4);
            app.ChangeAllLabel_2.Text = 'Change All:';
            app.PhaseShiftBxByBzPanel = uipanel(app.GridLayout6);
            app.PhaseShiftBxByBzPanel.Title = 'Phase Shift (Bx, By, Bz)';
            app.GridLayout9_5 = uigridlayout(app.PhaseShiftBxByBzPanel);
            app.bx = uieditfield(app.GridLayout9_5, 'numeric');
            app.bx.Limits = [0 6.2832];
            app.by = uieditfield(app.GridLayout9_5, 'numeric');
            app.by.Limits = [0 6.2832];
            app.bz = uieditfield(app.GridLayout9_5, 'numeric');
            app.bz.Limits = [0 6.2832];
            app.RotationPitchRollYawindegPanel = uipanel(app.GridLayout6);
            app.RotationPitchRollYawindegPanel.Title = 'Rotation (Pitch, Roll, Yaw in deg)';
            app.GridLayout9_6 = uigridlayout(app.RotationPitchRollYawindegPanel);
            app.pitch = uieditfield(app.GridLayout9_6, 'numeric');
            app.pitch.Limits = [0 180];
            app.roll = uieditfield(app.GridLayout9_6, 'numeric');
            app.roll.Limits = [0 180];
            app.yaw = uieditfield(app.GridLayout9_6, 'numeric');
            app.yaw.Limits = [0 180];
            app.OptionsToolsTab = uitab(app.TabGroup);
            app.OptionsToolsTab.Title = 'Options & Tools';
            app.GridLayout8 = uigridlayout(app.OptionsToolsTab);
            app.CalculationoptionsmayslowperformancePanel = uipanel(app.GridLayout8);
            app.CalculationoptionsmayslowperformancePanel.Title = 'Calculation options (may slow performance)';
            app.GridLayout10 = uigridlayout(app.CalculationoptionsmayslowperformancePanel);
            app.CurvatureMethodDropDownLabel = uilabel(app.GridLayout10);
            app.CurvatureMethodDropDownLabel.Text = 'Curvature Method';
            app.curvature = uidropdown(app.GridLayout10);
            app.curvature.Items = {'none', 'patch', 'trimesh', 'implicit'};
            app.curvature.Tooltip = {'Select the method for calculating curvature.'; ''; 'none - Don''t calculate curvature'; 'patch - Approximation type 1'; 'trimesh - Approximation type 2'; 'implicit - Uses math to find exact solution (may increase time for complex geometry)'};
            app.curvature.Value = 'patch';
            app.mechanical = uicheckbox(app.GridLayout10);
            app.mechanical.Text = 'Mechanical Homogenisation';
            app.ImportExportPanel = uipanel(app.GridLayout8);
            app.ImportExportPanel.Title = 'Import/Export';
            app.GridLayout10_3 = uigridlayout(app.ImportExportPanel);
            app.ExportmetrichistorycsvButton = uibutton(app.GridLayout10_3, 'push');
            app.ExportmetrichistorycsvButton.ButtonPushedFcn = createCallbackFcn(app, @exportMetrics, true);
            app.ExportmetrichistorycsvButton.Text = 'Export metric history (.csv)';
            app.ExportfigurefigButton = uibutton(app.GridLayout10_3, 'push');
            app.ExportfigurefigButton.ButtonPushedFcn = createCallbackFcn(app, @exportFig, true);
            app.ExportfigurefigButton.Text = 'Export figure (.fig)';
            app.ExportsurfacemeshstlButton = uibutton(app.GridLayout10_3, 'push');
            app.ExportsurfacemeshstlButton.ButtonPushedFcn = createCallbackFcn(app, @exportMesh, true);
            app.ExportsurfacemeshstlButton.Text = 'Export surface mesh (.stl)';
            app.ExportvoxelmeshinpButton = uibutton(app.GridLayout10_3, 'push');
            app.ExportvoxelmeshinpButton.ButtonPushedFcn = createCallbackFcn(app, @exportINP, true);
            app.ExportvoxelmeshinpButton.Text = 'Export voxel mesh (.inp)';
            app.ImportsurfacemeshstlButton = uibutton(app.GridLayout10_3, 'push');
            app.ImportsurfacemeshstlButton.ButtonPushedFcn = createCallbackFcn(app, @importSTL, true);
            app.ImportsurfacemeshstlButton.Text = 'Import surface mesh (.stl)';
            app.GridLayout11 = uigridlayout(app.GridLayout10_3);
            app.VoxelSizemmEditField = uieditfield(app.GridLayout11, 'numeric');
            app.VoxelSizemmEditField.Limits = [0 Inf];
            app.VoxelSizemmEditField.Value = 1;
            app.VoxelSizemmEditFieldLabel = uilabel(app.GridLayout11);
            app.VoxelSizemmEditFieldLabel.Text = ' Voxel Size (mm):';
            app.VisualisationOptionsPanel = uipanel(app.GridLayout8);
            app.VisualisationOptionsPanel.Title = 'Visualisation Options';
            app.GridLayout10_4 = uigridlayout(app.VisualisationOptionsPanel);
            app.HistogramBucketsSpinnerLabel = uilabel(app.GridLayout10_4);
            app.HistogramBucketsSpinnerLabel.Text = 'Histogram Buckets';
            app.hbuckets = uispinner(app.GridLayout10_4);
            app.hbuckets.Limits = [2 500];
            app.hbuckets.ValueChangedFcn = createCallbackFcn(app, @changeOpts, true);
            app.hbuckets.Value = 100;
            app.ColormapDropDownLabel = uilabel(app.GridLayout10_4);
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.cmap = uidropdown(app.GridLayout10_4);
            app.cmap.Items = {'linear', 'log'};
            app.cmap.ValueChangedFcn = createCallbackFcn(app, @changeOpts, true);
            app.cmap.Value = 'linear';
            app.PolemapdivisionsLabel = uilabel(app.GridLayout10_4);
            app.PolemapdivisionsLabel.Text = 'Polemap divisions';
            app.polemapdivs = uispinner(app.GridLayout10_4);
            app.polemapdivs.Limits = [1 5];
            app.polemapdivs.RoundFractionalValues = 'on';
            app.polemapdivs.ValueChangedFcn = createCallbackFcn(app, @changeOpts, true);
            app.polemapdivs.Tooltip = {'Number of division of the icosphere used for the polemap. (More divisions will require higher resolution meshing for good results)'};
            app.polemapdivs.Value = 3;
            app.ProcessDataButton = uibutton(app.GridLeft, 'push');
            app.ProcessDataButton.ButtonPushedFcn = createCallbackFcn(app, @updateButton, true);
            app.ProcessDataButton.Text = 'Update Data';
            app.ResetButton = uibutton(app.GridLeft, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButton, true);
            app.ResetButton.Text = 'Reset';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridRight = uigridlayout(app.RightPanel);
            app.ax = uiaxes(app.GridRight);
            xlabel(app.ax, 'X')
            ylabel(app.ax, 'Y')
            app.typeText = uilabel(app.GridRight);
            app.typeText.Text = 'Plot type';
            app.type = uidropdown(app.GridRight);
            app.type.Items = {'surfaceMesh', 'field viewer', 'voxel', 'histogram', 'histogram2', 'polemap', 'tensor', 'metrics scatterplot'};
            app.type.ValueChangedFcn = createCallbackFcn(app, @changeVisulisation, true);
            app.type.Tooltip = {'Visualisation types:'; ''; 'surfaceMesh - Mesh coloured by a selected property'; 'voxel - Voxelated model coloured by selected property'; 'histogram - Shows distribution of a given property'; 'histogram2 - Shows distribution of 2-property combinations'; 'polemap - Mapping of surface orientations onto a sphere'; 'field viewer - Show the value of a field at various Z-slices'; 'tensor - Show a visualisation of the cauchy stiffness tensor'; 'metric history - Shows the historically saved metric data in an xy scatterplot'};
            app.type.Value = 'surfaceMesh';
            app.FacePropertyLabel = uilabel(app.GridRight);
            app.FacePropertyLabel.Text = 'Property';
            app.P1 = uidropdown(app.GridRight);
            app.P1.Items = {'inclination'};
            app.P1.ValueChangedFcn = createCallbackFcn(app, @changeOpts, true);
            app.P1.Value = 'inclination';
            app.P2 = uidropdown(app.GridRight);
            app.P2.Items = {'Nz'};
            app.P2.ValueChangedFcn = createCallbackFcn(app, @changeOpts, true);
            app.P2.Visible = 'off';
            app.P2.Value = 'Nz';
            app.FaceProperty2Label = uilabel(app.GridRight);
            app.FaceProperty2Label.Visible = 'off';
            app.FaceProperty2Label.Text = 'Property 2';
            app.Slider = uislider(app.GridRight);
            app.Slider.MajorTicks = [0 50 100];
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @sliceSliderChanging, true);
            app.Slider.Visible = 'off';
            app.InitialisingLabel = uilabel(app.GridRight);
            app.InitialisingLabel.Text = 'Initialising';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TPMS_Designer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end